classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        NotReadyLabel_2             matlab.ui.control.Label
        NotReadyLabel               matlab.ui.control.Label
        ReadyLamp_2                 matlab.ui.control.Lamp
        ReadyLamp                   matlab.ui.control.Lamp
        ResolvingpuzzleButton       matlab.ui.control.Button
        CreatingpuzzleButton        matlab.ui.control.Button
        LoadingoriginalimageButton  matlab.ui.control.Button
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all;
            global state;
            state = 0;
        end

        % Button pushed function: LoadingoriginalimageButton
        function LoadingoriginalimageButtonPushed(app, event)
            global original_image;
            global state;
            [filename, pathname] = uigetfile('*.*', 'Pick an Image');
            filename=strcat(pathname,filename);
            original_image=imread(filename);
            imshow(original_image,'Parent',app.UIAxes);
            app.ReadyLamp.Color='green'
            app.NotReadyLabel.Text='Ready'
            state = 1;
        end

        % Button pushed function: CreatingpuzzleButton
        function CreatingpuzzleButtonPushed(app, event)
            global original_image;
            global puzzle;
            global state;
            if state > 0
                puzzle =imnoise(original_image,'salt & pepper', 0.4);
                imshow(puzzle,'Parent',app.UIAxes2);
                app.ReadyLamp_2.Color='green'
                app.NotReadyLabel_2.Text='Ready'
                state = 2; 
            end
        end

        % Button pushed function: ResolvingpuzzleButton
        function ResolvingpuzzleButtonPushed(app, event)
            global puzzle;
            global state;
            if state > 1
                p=medfilt3(puzzle,[5,5,3]);
                imshow(p,'Parent',app.UIAxes3);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original image')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Puzzle created')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Puzzle resolved')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.LoadingoriginalimageButton = uibutton(app.UIFigure, 'push');
            app.LoadingoriginalimageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadingoriginalimageButtonPushed, true);
            app.LoadingoriginalimageButton.Text = 'Loading original image';
            app.CreatingpuzzleButton = uibutton(app.UIFigure, 'push');
            app.CreatingpuzzleButton.ButtonPushedFcn = createCallbackFcn(app, @CreatingpuzzleButtonPushed, true);
            app.CreatingpuzzleButton.Text = 'Creating puzzle';
            app.ResolvingpuzzleButton = uibutton(app.UIFigure, 'push');
            app.ResolvingpuzzleButton.ButtonPushedFcn = createCallbackFcn(app, @ResolvingpuzzleButtonPushed, true);
            app.ResolvingpuzzleButton.Text = 'Resolving puzzle';
            app.ReadyLamp = uilamp(app.UIFigure);
            app.ReadyLamp_2 = uilamp(app.UIFigure);
            app.NotReadyLabel = uilabel(app.UIFigure);
            app.NotReadyLabel.Text = 'Not Ready';
            app.NotReadyLabel_2 = uilabel(app.UIFigure);
            app.NotReadyLabel_2.Text = 'Not Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end